classdef TunnelApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        DynamicPressureGauge            matlab.ui.control.LinearGauge
        DynamicPressureGaugeLabel       matlab.ui.control.Label
        AoAPortPressureDifferenceGauge  matlab.ui.control.LinearGauge
        AoAPortPressureDifferenceGaugeLabel  matlab.ui.control.Label
        AngleofAttackGauge              matlab.ui.control.SemicircularGauge
        AngleofAttackGaugeLabel         matlab.ui.control.Label
        MeasuredAirspeedGauge           matlab.ui.control.Gauge
        MeasuredAirspeedGaugeLabel      matlab.ui.control.Label
        STOPButton                      matlab.ui.control.Button
        SETSPEEDButton                  matlab.ui.control.Button
        SpeedSelectionKnob              matlab.ui.control.Knob
        SpeedSelectionKnobLabel         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PlotsTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ClearPlotButton_2               matlab.ui.control.Button
        ClearPlotButton                 matlab.ui.control.Button
        FreezeButton_2                  matlab.ui.control.Button
        FreezeButton                    matlab.ui.control.Button
        AoaPlot                         matlab.ui.control.UIAxes
        AspdPlot                        matlab.ui.control.UIAxes
        InfoTab                         matlab.ui.container.Tab
    end

    
    properties (Access = public)
        ams_sensors % Dict of AMS pressure sensors with key=address
        port_name = "COM3"  % Name of the serail port connected to the MC
        runTimer
        ref_aspd
        density = 1.225;
    end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
        function pressure = ams_toPascal(app, dp, p_min, p_max)
            % Description:
            %   Converts the differential pressure value output by an AMS5915 from 
            %   a 16 bit integer to its value in Pascals
            % Synopsis:
            %   pressure = ams_toPascal(dp, p_min, p_max)
            % Inputs:
            %   dp          The raw differential pressure value from the sensor
            %   p_min       The minimum p specified in the datasheet
            %   p_max       The maximum p specified in the datasheet
            % Outputs:
            %   pressure    The pressure in Pa

            dp_max = 14745;
            dp_min = 1638;
            sensp = (dp_max - dp_min)./(p_max - p_min);
        
            pressure = (dp - dp_min)./sensp + p_min;
        end

        function temp = ams_toCelsius(app, dt)
            % Description:
            %   Converts the raw temperature value output by an AMS5915 from an 8
            %   bit integer to its value in Celsius
            % Synopsis
            %   temp = ams_toCelsius(dt)
            % Inputs:
            %   dt          The temperature value from the sensor
            % Outputs:
            %   temp        The temperature in degrees Celsius
        
            temp = dt*200/2048 - 50;
        end
        
        function calculateAirspeed(app, diff_p)
            % Description:
            %   Calculates the airspeed based on a provided differential
            %   pressure
            % Inputs:
            %   diff_p      Differential pressure from total and static
            %               pressure ports

            % Calculate the airspeed (u = sqrt(2*dp/rho))
            app.ref_aspd = sqrt(2*abs(diff_p)/app.density);
            % Update the airspeed gauge
            app.MeasuredAirspeedGauge.Value = app.ref_aspd;
        end

        function updateDynamicP(app)
            % Description:
            %   Updates the dynamic pressure gauge with the latest value
            %   from the pitot-static tube pressure sensor
            app.DynamicPressureGauge.Value = app.ams_sensors(18).p_values(end);
        end

        function [] = readData(app)
            disp("entered FCN")
            num_lines = 4;  % Number of serial lines to read
            port = serialport(app.port_name, 9600); % Initialize the serial port
            flush(port); % Clear unread messages
            data = [];
            while port.NumBytesAvailable < 4   % Wait until the serial port is ready
            end
            
            for i = 1:num_lines
                if i == 1
                    useless = readline(port);   % Dump the first line to an unused variable
                end
                string = readline(port);
                line = str2double(strsplit(string, "\t"));
                data = [data; line];
            end
            clear port
            
            % Update the tempearture and pressure data for the AMS sensor
            % objects.
            for i = 1:num_lines
                addr = data(i, 1);
                raw_pres = data(i, 2);
                raw_temp = data(i, 3);
                p_min = app.ams_sensors(addr).p_min;
                p_max = app.ams_sensors(addr).p_max;
                pres = ams_toPascal(app, raw_pres, p_min, p_max);
                temp = ams_toCelsius(app, raw_temp);
            
                app.ams_sensors(addr).p_values = [app.ams_sensors(addr).p_values, pres];
                app.ams_sensors(addr).t_values = [app.ams_sensors(addr).t_values, temp];
            end

            plot(app.AspdPlot, app.ams_sensors(17).p_values)
            plot(app.AoaPlot, app.ams_sensors(31).p_values)
            
            calculateAirspeed(app, app.ams_sensors(18).p_values(end));
            updateDynamicP(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p1 = AMSSensor(-2000, 2000);    % AoA
            p2 = AMSSensor(-2000, 2000);
            p3 = AMSSensor(0, 2000);        % Ref pitot
            p4 = AMSSensor(0, 2000);        % UUT
            app.ams_sensors = dictionary(31, p1, 30, p2, 18, p3, 17, p4);
            

            app.runTimer = timer("ExecutionMode", "fixedspacing", ...
               "Period", 0.1, "TasksToExecute", Inf, ...
               "BusyMode", "queue");
            app.runTimer.TimerFcn = @(~, ~)app.readData();
            start(app.runTimer);
            disp("Finished Startup")
            
        end

        % Button pushed function: SETSPEEDButton
        function SETSPEEDButtonPushed(app, event)
            spdSetting = app.SpeedSelectionKnob.Value;
            port = serialport(app.port_name, 9600); % Initialize the serial port
            flush(port);
            writeline(port, num2str(spdSetting));
            clear port;
            disp("Set Speed")
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            port = serialport(app.port_name, 9600); % Initialize the serial port
            flush(port);
            writeline(port, "0");
            delete(port);
            app.SpeedSelectionKnob.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout2 = uigridlayout(app.PlotsTab);
            app.AspdPlot = uiaxes(app.GridLayout2);
            title(app.AspdPlot, 'UUT Airspeed')
            xlabel(app.AspdPlot, 'X')
            ylabel(app.AspdPlot, 'Y')
            zlabel(app.AspdPlot, 'Z')
            app.AoaPlot = uiaxes(app.GridLayout2);
            title(app.AoaPlot, 'UUT Angle of Attack')
            xlabel(app.AoaPlot, 'X')
            ylabel(app.AoaPlot, 'Y')
            zlabel(app.AoaPlot, 'Z')
            app.FreezeButton = uibutton(app.GridLayout2, 'push');
            app.FreezeButton.Text = 'Freeze';
            app.FreezeButton_2 = uibutton(app.GridLayout2, 'push');
            app.FreezeButton_2.Text = 'Freeze';
            app.ClearPlotButton = uibutton(app.GridLayout2, 'push');
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ClearPlotButton_2 = uibutton(app.GridLayout2, 'push');
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.SpeedSelectionKnobLabel = uilabel(app.GridLayout);
            app.SpeedSelectionKnobLabel.Text = 'Speed Selection';
            app.SpeedSelectionKnob = uiknob(app.GridLayout, 'continuous');
            app.SpeedSelectionKnob.Limits = [0 80];
            app.SpeedSelectionKnob.MajorTicks = [0 10 20 30 40 50 60 70 80];
            app.SETSPEEDButton = uibutton(app.GridLayout, 'push');
            app.SETSPEEDButton.ButtonPushedFcn = createCallbackFcn(app, @SETSPEEDButtonPushed, true);
            app.SETSPEEDButton.Text = 'SET SPEED';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.MeasuredAirspeedGaugeLabel = uilabel(app.GridLayout);
            app.MeasuredAirspeedGaugeLabel.Text = 'Measured Airspeed';
            app.MeasuredAirspeedGauge = uigauge(app.GridLayout, 'circular');
            app.MeasuredAirspeedGauge.Limits = [0 80];
            app.MeasuredAirspeedGauge.MajorTicks = [0 10 20 30 40 50 60 70 80];
            app.AngleofAttackGaugeLabel = uilabel(app.GridLayout);
            app.AngleofAttackGaugeLabel.Text = 'Angle of Attack';
            app.AngleofAttackGauge = uigauge(app.GridLayout, 'semicircular');
            app.AngleofAttackGauge.Limits = [-50 50];
            app.AngleofAttackGauge.MajorTicks = [-45 -35 -25 -15 -5 5 15 25 35 45];
            app.AngleofAttackGauge.MinorTicks = [-45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45];
            app.AoAPortPressureDifferenceGaugeLabel = uilabel(app.GridLayout);
            app.AoAPortPressureDifferenceGaugeLabel.Text = 'AoA Port Pressure Difference';
            app.AoAPortPressureDifferenceGauge = uigauge(app.GridLayout, 'linear');
            app.AoAPortPressureDifferenceGauge.Limits = [-100 100];
            app.DynamicPressureGaugeLabel = uilabel(app.GridLayout);
            app.DynamicPressureGaugeLabel.Text = 'Dynamic Pressure';
            app.DynamicPressureGauge = uigauge(app.GridLayout, 'linear');
            app.DynamicPressureGauge.Limits = [0 3000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TunnelApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end